classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ReadRawCSVFileTab             matlab.ui.container.Tab
        LoadCSVFileFromComputerLabel  matlab.ui.control.Label
        LOADButton                    matlab.ui.control.Button
        ReadWriteofCSVFileTab         matlab.ui.container.Tab
        selectanalreadycreatedCSVFileLabel  matlab.ui.control.Label
        SAVEButton                    matlab.ui.control.Button
        writetheCSVFilewithprocesseddataLabel  matlab.ui.control.Label
        SELECTButton                  matlab.ui.control.Button
        DataprocessingTab             matlab.ui.container.Tab
        ClickButtonforDataProcessingLabel  matlab.ui.control.Label
        ComputeButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        DatavisualizationTab          matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        PlotButton                    matlab.ui.control.Button
        EnterarbitrarydaynumberEditFieldLabel  matlab.ui.control.Label
        EnterarbitrarydaynumberEditField  matlab.ui.control.NumericEditField
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            %1st Main Task: Read Raw CSV-File
            global T_new filename
            
            [filename,pathname]=uigetfile("*.csv"); %open raw csv file (data)
            fullfilename=fullfile(pathname,filename);  %extract the file name 
            T=readtable(fullfilename);                  %read the data as table
                      
            %extract Relevant data
            Date=T.Date_MM_DD_YYYY_;            %date
            Hours=hour(T.Time_HH_MM_);          %Hourly extraterrestrial radiation on a horizontal surface
            direct_normal=T.DNI_W_m_2_;         %Direct normal irradiance
            total_skycover=T.TotCld_tenths_;    %Total sky cover
            dry_bulb_temp=T.Dry_bulb_C_;        %Dry Bulb Temperature
            station_pressure=T.Pressure_mbar_;  %Station Pressure
            wind_direction=T.Wdir_degrees_;     %Wind Direction
            wind_speed=T.Wspd_m_s_;             %Wind Speed=hour(T.Time_HH_MM_);
            
            %create Table for the relevant data / store the relevant data in table      
            T_new=table(Date,Hours,direct_normal,total_skycover,dry_bulb_temp,station_pressure,wind_direction,wind_speed);
      
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            global T_new filename
            
            filename=strcat('processed data/',filename);
            writetable(T_new,filename)
            
        end

        % Button pushed function: SELECTButton
        function SELECTButtonPushed(app, event)
            
            %2nd Main Task: Read/Write CSV-File with selected data
            global T_new filename
            
            nofiles=length(dir(fullfile('processed data','*.CSV'))); %check number of files
            
            if nofiles==0
                return
            end
            
            [filename,pathname]=uigetfile("*.csv"); %open processed File
            fullfilename=fullfile(pathname,filename);  %extract the file name 
            T_new=readtable(fullfilename);                  %read the data as table
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            %3rd Main Task: Data processing
            global T_new
            
            T_min=min(table2array(T_new(:,3:end)));       %minimum for all variables in data
            T_max=max(table2array(T_new(:,3:end)));       %maximum for all variables in data
            T_mean=mean(table2array(T_new(:,3:end)));     %mean for all variables in data
            T_median=median(table2array(T_new(:,3:end))); %median for all variables in data
            T_std=std (table2array(T_new(:,3:end)));      %standard deviation for all variables in data
            
            %creating Table Output
            
            Min=T_min';
            Max=T_max';
            Mean=T_mean';
            Median=T_median';
            Std=T_std';
            
            %set Row Names
            Rnames={'Direct normal irradiance','Total sky cover','Dry-bulb temperature',...
                'Station pressure','Wind direction','Wind speed'};
            %create Tables
            T_processed=table(Min,Max,Mean,Median,Std, 'RowNames',Rnames);
            app.UITable.Data=T_processed;
            app.UITable.RowName=Rnames;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %4th Main Task: Data visualization
            global T_new
            
            days=unique(T_new.Date); %create unique value for all the days
            
            arbdaynum=app.EnterarbitrarydaynumberEditField.Value;  %select arbitrary day
            
            %return if the number of day is more than the dataset
            if arbdaynum>length(days)
                return
            end
            arbday=days(arbdaynum);  %extract the particular day
            
            T_sub=T_new(T_new.Date==arbday,:);  %extract sub data for the day
            [~,idx]=sort(T_sub.Hours);       %sort hour for ploting
            
            
            %plot

            plot(app.UIAxes,T_sub.Hours(idx),T_sub.total_skycover(idx))
          
            plot(app.UIAxes3,T_sub.Hours(idx),T_sub.dry_bulb_temp(idx))
       
            plot(app.UIAxes4,T_sub.Hours(idx),T_sub.station_pressure(idx))     
            
            plot(app.UIAxes5,T_sub.Hours(idx),T_sub.wind_speed(idx))
        
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadRawCSVFileTab = uitab(app.TabGroup);
            app.ReadRawCSVFileTab.Title = 'Read Raw CSV-File';
            app.LoadCSVFileFromComputerLabel = uilabel(app.ReadRawCSVFileTab);
            app.LoadCSVFileFromComputerLabel.Text = 'Load CSV File From Computer ';
            app.LOADButton = uibutton(app.ReadRawCSVFileTab, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.ReadWriteofCSVFileTab = uitab(app.TabGroup);
            app.ReadWriteofCSVFileTab.Title = 'Read/Write of CSV-File';
            app.selectanalreadycreatedCSVFileLabel = uilabel(app.ReadWriteofCSVFileTab);
            app.selectanalreadycreatedCSVFileLabel.Text = ' select an already created CSV-File';
            app.SAVEButton = uibutton(app.ReadWriteofCSVFileTab, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.writetheCSVFilewithprocesseddataLabel = uilabel(app.ReadWriteofCSVFileTab);
            app.writetheCSVFilewithprocesseddataLabel.Text = 'write the CSV-File with processed data';
            app.SELECTButton = uibutton(app.ReadWriteofCSVFileTab, 'push');
            app.SELECTButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTButtonPushed, true);
            app.SELECTButton.Text = 'SELECT';
            app.DataprocessingTab = uitab(app.TabGroup);
            app.DataprocessingTab.Title = 'Data processing';
            app.ClickButtonforDataProcessingLabel = uilabel(app.DataprocessingTab);
            app.ClickButtonforDataProcessingLabel.Text = 'Click Button for Data Processing';
            app.ComputeButton = uibutton(app.DataprocessingTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute ';
            app.UITable = uitable(app.DataprocessingTab);
            app.UITable.ColumnName = {'Min'; 'Max'; 'Mean'; 'Median'; 'Standard Deviation'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false true];
            app.DatavisualizationTab = uitab(app.TabGroup);
            app.DatavisualizationTab.Title = 'Data visualization';
            app.UIAxes = uiaxes(app.DatavisualizationTab);
            title(app.UIAxes, 'Total skycover Vs Hour')
            xlabel(app.UIAxes, 'Hour')
            ylabel(app.UIAxes, 'Total skycover')
            app.PlotButton = uibutton(app.DatavisualizationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EnterarbitrarydaynumberEditFieldLabel = uilabel(app.DatavisualizationTab);
            app.EnterarbitrarydaynumberEditFieldLabel.Text = 'Enter arbitrary day number';
            app.EnterarbitrarydaynumberEditField = uieditfield(app.DatavisualizationTab, 'numeric');
            app.EnterarbitrarydaynumberEditField.Value = 5;
            app.UIAxes3 = uiaxes(app.DatavisualizationTab);
            title(app.UIAxes3, 'Dry Bulb Temperature Vs Hour')
            xlabel(app.UIAxes3, 'Hour')
            ylabel(app.UIAxes3, 'Dry Bulb Temperature')
            app.UIAxes4 = uiaxes(app.DatavisualizationTab);
            title(app.UIAxes4, 'Station Pressure Vs Hour')
            xlabel(app.UIAxes4, 'Hour')
            ylabel(app.UIAxes4, 'Station Pressure')
            app.UIAxes5 = uiaxes(app.DatavisualizationTab);
            title(app.UIAxes5, 'wind speed Vs Hour')
            xlabel(app.UIAxes5, 'Hour')
            ylabel(app.UIAxes5, 'wind speed')
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end